classdef cdr_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CoefficientofRestitutionEditField_2  matlab.ui.control.NumericEditField
        CoefficientofRestitutionEditField_2Label  matlab.ui.control.Label
        InorOutEditField             matlab.ui.control.EditField
        InorOutLabel                 matlab.ui.control.Label
        SelectShotDropDown           matlab.ui.control.DropDown
        SelectShotLabel              matlab.ui.control.Label
        RunButton                    matlab.ui.control.Button
        SinglesorDoublesButtonGroup  matlab.ui.container.ButtonGroup
        SinglesButton                matlab.ui.control.RadioButton
        DoublesButton                matlab.ui.control.RadioButton
        VolleyorServeButtonGroup     matlab.ui.container.ButtonGroup
        ServeButton                  matlab.ui.control.RadioButton
        VolleyButton                 matlab.ui.control.RadioButton
        InputParametersLabel         matlab.ui.control.Label
        OuputParametersLabel         matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        CalculateButton              matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    properties (Access = public)
        % DELETE LATER - KEEP AND UPDATE WHILE GUI IS W.I.P
        % app.Lamp flashes depending on in/out mode
       
        MODE;           % 1 -> coefficient of restitution, 2 -> ball is inside or out
        MATCH_TYPE;     % 1 -> singles mode, -> 2 doubles mode
        SHOT_TYPE;      % 1 -> serve mode, 2 -> volley mode
        path;           % path to .dat file in datFiles folder; contains ball info

        isShotIn;
        restitutionCoeff;
        xCoeff;
        yCoeff;
        bounceT;
        gcf;            %figure

        % get 6 things back from matlab client
        % calls function with mode/match_type/shot_type/path to shot
        % outputs:
        % in/out 1 -> in, 0 -> out
        % coeff restitution
        % coeff x
        % coeff y
        % bounce t
        % figure

    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function resetAxes(app)
            % Clear Axes
            cla(app.UIAxes);

            % Start Plotting the Court Lines
            hold(app.UIAxes, 'on');
            

            % Copy and paste in Line Piecewises
            syms x;
            doublesTopline = piecewise(-11.865 < x & 11.885, 5.485, 5.485);
            singlesTopline = piecewise(-11.865 < x & 11.885, 4.115, 4.115);
            centerLine = piecewise(-6.4 < x & x < 6.4, 0, 0);
            singlesBottomline = piecewise(-11.865 < x & 11.885, -4.115, -4.115);
            doublesBottomline = piecewise(-11.865 < x & 11.885, -5.485, -5.485);

            toplineDoublesCourt = ones(1, 238);
            toplineSinglesCourt = ones(1, 238);
            centerLineCourt = ones(1, 129);
            bottomlineDoublesCourt = ones(1, 238);
            bottomlineSinglesCourt = ones(1, 238);

            i = 1;
            for j = -11.865: 0.1 : 11.865
                toplineDoublesCourt(i)    = subs(doublesTopline, x, j);
                bottomlineDoublesCourt(i) = subs(doublesBottomline, x, j);
                toplineSinglesCourt(i)  = subs(singlesTopline, x, j);
                bottomlineSinglesCourt(i) = subs(singlesBottomline, x, j);
                i = i + 1;
            end

            i = 1;
            for j = -6.4 : 0.1 : 6.4
                centerLineCourt(i) = subs(centerLine, x, j);
                i = i + 1;
            end

            syms y
            leftLine = piecewise(-5.485 < y & y < 5.485, -11.865, -11.865);
            leftServiceLine = piecewise(-4.115 < y & y < 4.115, -6.4, -6.4);
            netLine = piecewise(-5.485 < y & y < 5.485, 0, 0);
            rightServiceLine = piecewise(-4.115 < y & y < 4.115, 6.4, 6.4);
            rightLine = piecewise(-5.485 < y & y < 5.485, 11.865, 11.865);

            leftLineCourt = ones(1, 110);
            leftServiceLineCourt = ones(1, 83);
            netlineCourt = ones(1, 110);
            rightServiceLineCourt = ones(1, 83);
            rightLineCourt = ones(1, 110);

            i = 1;
            for j = -5.485 : 0.1 : 5.485
                leftLineCourt(i) = subs(leftLine, y, j);
                netlineCourt(i) = subs(netLine, y, j);
                rightLineCourt(i) = subs(rightLine, y, j);
                i = i + 1;
            end

            i = 1;
            for j = -4.115 : 0.1 : 4.115
                leftServiceLineCourt(i) = subs(leftServiceLine, y, j);
                rightServiceLineCourt(i) = subs(rightServiceLine, y, j);
                i = i + 1;
            end

            plot(app.UIAxes, [ -11.865: 0.1 : 11.865], toplineDoublesCourt,'black');
            plot(app.UIAxes, [ -11.865: 0.1 : 11.865], toplineSinglesCourt, 'black');
            plot(app.UIAxes, [-6.4 : 0.1 : 6.4], centerLineCourt, 'black');
            plot(app.UIAxes, [ -11.865: 0.1 : 11.865], bottomlineSinglesCourt, 'black');
            plot(app.UIAxes, [ -11.865: 0.1 : 11.865], bottomlineDoublesCourt, 'black');
            plot(app.UIAxes, leftLineCourt, [-5.485 : 0.1 : 5.485], 'black');
            plot(app.UIAxes, netlineCourt, [-5.485 : 0.1 : 5.485], 'black');
            plot(app.UIAxes, leftServiceLineCourt, [-4.115 : 0.1 : 4.115], 'black');
            plot(app.UIAxes, rightServiceLineCourt, [-4.115 : 0.1 : 4.115], 'black');
            plot(app.UIAxes, rightLineCourt, [-5.485 : 0.1 : 5.485], 'black');

        end

        function plotTrajectory(app, xCoeff, yCoeff, bounceT)
            % Create polynomial to plot
            timeVector = 1:2000; % Evaulate x,y positions from 1-2000 ms
            xCoeff = -xCoeff;
            xValues = polyval(xCoeff, timeVector);
            yValues = polyval(yCoeff, timeVector);

            % Find Bounce Location
            xMarker = polyval(xCoeff, bounceT);
            yMarker = polyval(yCoeff, bounceT);

            % Start Plotting the trajectory
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, xValues, yValues, "r")
            % Show bounce spot
            plot(app.UIAxes, xMarker, yMarker, 'ro', 'MarkerSize', 8, 'LineWidth',2);
            hold(app.UIAxes, 'off');
            
        end
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MODE = 1;
            app.MATCH_TYPE = 1;
            app.SHOT_TYPE = 1;
            app.path = "";
            app.InorOutEditField.Value = "N/A";
            app.CoefficientofRestitutionEditField_2.Value = 0;
            resetAxes(app)
            app.Lamp.Color = [0 0 0]; %off
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % add comment later
            % reset field until new answer found
            app.InorOutEditField.Value = "N/A";
            app.CoefficientofRestitutionEditField_2.Value = 0;
            app.MODE = 2;
            resetAxes(app);
            % Make Graph Blank again

            % INisShotIn = 2;
            % INrestitutionCoeff = 1.0;
            % INxCoeff = 1.0;
            % INyCoeff = 1.0;
            % INbounceT = 1.0;
            [INisShotIn, INrestitutionCoeff, INxCoeff, INyCoeff, INbounceT] = MatlabClientAPI(app.MODE, app.MATCH_TYPE, app.SHOT_TYPE, app.path);

            app.isShotIn = uint8(INisShotIn);
            app.restitutionCoeff = double(INrestitutionCoeff);
            app.xCoeff = double(INxCoeff);
            app.yCoeff = double(INyCoeff);
            app.bounceT = double(INbounceT);
            disp(app.isShotIn)

            if (app.isShotIn == 0.0)
                app.InorOutEditField.Value = "OUT";
            else 
                app.InorOutEditField.Value = "IN";
            end
            app.CoefficientofRestitutionEditField_2.Value = 0;
            app.plotTrajectory(app.xCoeff, app.yCoeff, app.bounceT)

            if app.InorOutEditField.Value == "IN"          % when ball is in
                app.Lamp.Color = [0 1 0];             %stays green 
            elseif app.InorOutEditField.Value == "OUT"         % when ball is out
                for i = 1:60
                    app.Lamp.Color = [1 0 0];         % flash red
                    pause(50/1000);                 %pause 100ms
                    app.Lamp.Color = [0 0 0];         %off (gray)
                    pause(50/1000);   
                end
            else % any other situation
                app.Lamp.Color = [0 0 0]; %off
            end

            % Show graph somehow
        end

        % Selection changed function: SinglesorDoublesButtonGroup
        function SinglesorDoublesButtonGroupSelectionChanged(app, event)
            % Selector for shot in single or doubles, returns value
            % MATCH_TYPE; 1 -> singles mode, -> 2 doubles mode
            % app.SinglesButton
            % app.DoublesButton
            selectedButton = app.SinglesorDoublesButtonGroup.SelectedObject;

            if selectedButton == app.SinglesButton      % 1 -> singles mode
                app.MATCH_TYPE = 1;                     
            elseif selectedButton == app.DoublesButton  % 2 -> doubles mode
                app.MATCH_TYPE = 2;
            end
        end

        % Selection changed function: VolleyorServeButtonGroup
        function VolleyorServeButtonGroupSelectionChanged(app, event)
            % Selector for serve or volley shot, returns value
            % SHOT_TYPE; 1 -> serve mode, 2 -> volley mode
            % app.ServeButton
            % app.VolleyButton
            selectedButton = app.VolleyorServeButtonGroup.SelectedObject;
            
            if selectedButton == app.ServeButton        % 1 -> serve mode
                app.SHOT_TYPE = 1;
            elseif selectedButton == app.VolleyButton   % 2 -> volley mode
                app.SHOT_TYPE = 2;
            end
        end

        % Value changed function: SelectShotDropDown
        function SelectShotDropDownValueChanged(app, event)
            % item values = Shot 1, Shot 2, Shot 3, Shot 4, Shot 5
            % item values can be manually added in the dropdown list using
            % the + button, and deleted using the - minus button
            % Case statement? Or grab number from shot # and append to call
            % to file location to grab .dat from datFiles

            selectedItem = app.SelectShotDropDown.Value;
            dataSetNum = selectedItem(6); % take string of item and grabs just the #
            if app.SHOT_TYPE == 1                                           % 1 -> serve mode
                app.path = "../datFiles/serve" + dataSetNum + ".dat";       % concats data set # to serve
            elseif app.SHOT_TYPE == 2                                       % 2 -> doubles mode
                app.path = "../datFiles/volley" + dataSetNum + ".dat";      % concats data set # to volley
            end
                
        end

        % Value changed function: InorOutEditField
        function InorOutEditFieldValueChanged(app, event)
            value = app.InorOutEditField.Value;
  
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.InorOutEditField.Value = "N/A";
            app.Lamp.Color = [0 0 0]; %off
            app.CoefficientofRestitutionEditField_2.Value = 0;
            resetAxes(app)
            % Make Graph Blank again
            app.MODE = 1;
            app.MATCH_TYPE = 1;
            app.SHOT_TYPE = 1;
            app.path = "../datFiles/bounce.dat";
            
            [app.isShotIn, app.restitutionCoeff, app.xCoeff, app.yCoeff, app.bounceT] = MatlabClientAPI(app.MODE, app.MATCH_TYPE, app.SHOT_TYPE, app.path);
            app.CoefficientofRestitutionEditField_2.Value = app.restitutionCoeff;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Ball Trajectory')
            app.UIAxes.XLim = [-12 12];
            app.UIAxes.YLim = [-6 6];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Lamp = uilamp(app.UIFigure);
            app.OuputParametersLabel = uilabel(app.UIFigure);
            app.OuputParametersLabel.Text = 'Ouput Parameters';
            app.InputParametersLabel = uilabel(app.UIFigure);
            app.InputParametersLabel.Text = 'Input Parameters';
            app.VolleyorServeButtonGroup = uibuttongroup(app.UIFigure);
            app.VolleyorServeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VolleyorServeButtonGroupSelectionChanged, true);
            app.VolleyorServeButtonGroup.Title = 'Volley or Serve?';
            app.VolleyButton = uiradiobutton(app.VolleyorServeButtonGroup);
            app.VolleyButton.Text = 'Volley';
            app.ServeButton = uiradiobutton(app.VolleyorServeButtonGroup);
            app.ServeButton.Text = 'Serve';
            app.ServeButton.Value = true;
            app.SinglesorDoublesButtonGroup = uibuttongroup(app.UIFigure);
            app.SinglesorDoublesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SinglesorDoublesButtonGroupSelectionChanged, true);
            app.SinglesorDoublesButtonGroup.Title = 'Singles or Doubles?';
            app.DoublesButton = uiradiobutton(app.SinglesorDoublesButtonGroup);
            app.DoublesButton.Text = 'Doubles';
            app.SinglesButton = uiradiobutton(app.SinglesorDoublesButtonGroup);
            app.SinglesButton.Text = 'Singles';
            app.SinglesButton.Value = true;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SelectShotLabel = uilabel(app.UIFigure);
            app.SelectShotLabel.Text = 'Select Shot: ';
            app.SelectShotDropDown = uidropdown(app.UIFigure);
            app.SelectShotDropDown.Items = {'Shot 1', 'Shot 2', 'Shot 3', 'Shot 4', 'Shot 5'};
            app.SelectShotDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectShotDropDownValueChanged, true);
            app.SelectShotDropDown.Placeholder = 'Shot 1';
            app.SelectShotDropDown.Value = 'Shot 1';
            app.InorOutLabel = uilabel(app.UIFigure);
            app.InorOutLabel.Text = 'In or Out?    ';
            app.InorOutEditField = uieditfield(app.UIFigure, 'text');
            app.InorOutEditField.ValueChangedFcn = createCallbackFcn(app, @InorOutEditFieldValueChanged, true);
            app.InorOutEditField.Value = 'N/A';
            app.CoefficientofRestitutionEditField_2Label = uilabel(app.UIFigure);
            app.CoefficientofRestitutionEditField_2Label.Text = 'Coefficient of Restitution';
            app.CoefficientofRestitutionEditField_2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cdr_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end